classdef APTC_AcousticCalibration < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                     matlab.ui.Figure
      MethodDropDown               matlab.ui.control.DropDown
      MeasureButton                matlab.ui.control.Button
      MeterButton                  matlab.ui.control.Button
      TransducerDropDown           matlab.ui.control.DropDown
      TransducerDropDownLabel      matlab.ui.control.Label
      Panel                        matlab.ui.container.Panel
      UITable                      matlab.ui.control.Table
      ExitButton                   matlab.ui.control.Button
      SaveButton                   matlab.ui.control.Button
      PlayButton                   matlab.ui.control.Button
      AttenEditField               matlab.ui.control.NumericEditField
      AttenuationdBEditFieldLabel  matlab.ui.control.Label
      StopButton                   matlab.ui.control.Button
      StopMeasureButton            matlab.ui.control.Button
   end

   
   properties (Access = private)
      calFolder
      calPath % Description
      attenuation % Description
      isDirty % Description
      haudio % Description
      freq % Description
      transducer % Description
      soundMeter % Description
      stopMeasurement % Description
      volumeControl = []
   end
   
   methods (Access = private)
      
      function OnAudioFinished(app, ~, ~)
         app.UITable.Enable = 'on';
         app.PlayButton.Visible = true;
      end
      
      %--------------------------------------------------------------------
      function SaveData(app)
         oldSPL = app.UITable.Data.Old;         
         newSPL = app.UITable.Data.New;
         spl = NaN(size(newSPL));
         for k = 1:length(newSPL)
            if isempty(newSPL{k})
               spl(k) = str2double(oldSPL(k,:));
            else
               spl(k) = str2double(newSPL{k});
            end
         end
         cal.FreqHz = app.freq;
         cal.SPL_Vrms = spl;
         save(app.calPath, 'cal', '-append');

         app.isDirty = false;
      end
      
      %--------------------------------------------------------------------
      function LoadCalibration(app)
         app.calPath = fullfile(app.calFolder, [app.transducer '_Calibration.mat']);
         if ~exist(app.calPath, 'file')
            cal.FreqHz = app.freq;
            cal.SPL_Vrms = NaN*ones(size(app.freq));
            save(app.calPath, 'cal');
         end

         load(app.calPath, 'cal');
         Lmax = interp1(cal.FreqHz, cal.SPL_Vrms, app.freq);

         app.UITable.RowName = {};
         d = table(num2str(app.freq, '%d'), num2str(Lmax, '%.1f'), cell(size(app.freq)), cell(size(app.freq)), 'VariableNames', {'Freq', 'Old', 'New', 'Measured'});
         app.UITable.Data = d;       
         app.UITable.addStyle(uistyle('HorizontalAlignment', 'right'), 'table', '');

         app.isDirty = false;
      end
      
      %--------------------------------------------------------------------
      function AcquireData(app)
         ifreq = app.UITable.Selection;
         if isempty(ifreq)
            return;
         end

         app.stopMeasurement = false;
         app.PlayButton.Enable = false;
         app.StopMeasureButton.Enable = true;
         app.MeasureButton.Visible = false;

         Fs = 50000;
         T = 30;
         npts = round(Fs * T);
         t = (0:npts-1) / Fs;

         try
            app.SetPCVolume();

             di = audiodevinfo();
             imotu = find(contains({di.output.Name}, 'Speakers (MOTU Pro Audio)'));
             if isempty(imotu)
                 error('Cannot find MOTU speaker channels');
             end

            for kf = 1:length(ifreq)

               app.UITable.Selection = ifreq(kf);
               f = str2double(app.UITable.Data.Freq(ifreq(kf),:));

               y = sin(2*pi*t*f);
               y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

               h = audioplayer(y, Fs, 24, di.output(imotu).ID); %#ok<TNMLP>
               play(h);

               value = app.soundMeter.AcquireValue();
               app.UITable.Data.Measured(ifreq(kf)) = {num2str(value, '%.1f')};
               app.UITable.Data.New(ifreq(kf)) = {num2str(value - app.attenuation, '%.1f')};
               app.isDirty = true;
               stop(h);

               if app.stopMeasurement
                  break;
               end
            end
         catch ex
            errordlg(ex.message);
         end

         app.PlayButton.Enable = true;
         app.MeasureButton.Visible = true;
         app.UITable.Enable = 'on';
      end

      %--------------------------------------------------------------------
      function SetPCVolume(app)
         if ~isempty(app.volumeControl)
            app.volumeControl.SetVolume(0.25);
         end         
      end
      
      %--------------------------------------------------------------------
      function InitializeVolumeControl(app)
         try
            NET.addAssembly(fullfile(fileparts(which(mfilename)), 'KLib.VolumeManager.dll'));
            app.volumeControl = KLib.VolumeManager;
            app.volumeControl.SetVolume(0.25);
         catch ex
            errordlg(['Failed to initialize volume control: ' newline ex.message newline 'Make sure PC volume is maxed out.'], 'UWCAMP');
            app.volumeControl = [];
         end
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, freq, calFolder, transducer)
         app.freq = freq(:);
         app.calFolder = calFolder;
         app.transducer = transducer;

         % Restore attenuation
         app.attenuation = getpref('APTC_AcousticCalibration', 'Attenuation', -20);
         app.AttenEditField.Value = app.attenuation;

         % Restore figure position
         pos = app.UIFigure.Position;
         monitorPos = get(0, 'MonitorPositions');
         iprimary = find(monitorPos(:, 1) == 1);
         x = 0.5*(monitorPos(iprimary, 3) - pos(3));
         y = 0.5*(monitorPos(iprimary, 4) - pos(4));
%          rmpref('APTC_AcousticCalibration', 'Position');
         origin = getpref('APTC_AcousticCalibration', 'Position', [x y pos(3:4)]);
         if ~isempty(origin)
            pos(1:2) = origin(1:2);
            app.UIFigure.Position = pos;
         end

         % Fill transducer drop down
         flist = dir(fullfile(app.calFolder, '*_Calibration.mat'));
         t = {};
         for k = 1:length(flist)
            parts = strsplit(flist(k).name, '_Calibration');
            t{end+1} = parts{1}; %#ok<AGROW> 
         end
         app.TransducerDropDown.Items = t;
         app.TransducerDropDown.Value = app.transducer;

         app.LoadCalibration();

         app.InitializeVolumeControl();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if app.isDirty
            response = questdlg('You have unsaved changes to the calibration.', '', 'Save', 'Don''t save', 'Cancel', 'Save');
            if isequal(response, 'Cancel')
               return;
            elseif isequal(response, 'Save')
               app.SaveData();
            end
         end
         
        if ~isempty(app.soundMeter)
            app.soundMeter.Quit();
        end

         pos = app.UIFigure.Position;
         setpref('APTC_AcousticCalibration', 'Position', pos(1:2));
         setpref('APTC_AcousticCalibration', 'Attenuation', app.attenuation);

         uiresume(app.UIFigure);

         delete(app)
      end

      % Button pushed function: ExitButton
      function ExitButtonPushed(app, event)
         close(app.UIFigure);
      end

      % Value changed function: AttenEditField
      function AttenEditFieldValueChanged(app, event)
         app.attenuation = app.AttenEditField.Value;         
      end

      % Button pushed function: PlayButton
      function PlayButtonPushed(app, event)
         ifreq = app.UITable.Selection;
         if isempty(ifreq) || length(ifreq) > 1
            return;
         end
         f = str2double(app.UITable.Data.Freq(ifreq,:));

         Fs = 50000;
         T = 30;
         npts = round(Fs * T);
         t = (0:npts-1) / Fs;
         y = sin(2*pi*t(:)*f);
         y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

         app.UITable.Enable = 'off';

         di = audiodevinfo();
         imotu = find(contains([di.output.Name], 'Speakers (MOTU Pro Audio)'));
         if isempty(imotu)
             error('Cannot find MOTU speaker channels');
         end

         app.SetPCVolume()
         app.haudio = audioplayer([y y], Fs, 24, di.output(imotu).ID);
         app.haudio.StopFcn = @app.OnAudioFinished;

         app.PlayButton.Visible = false;
         play(app.haudio);
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         stop(app.haudio);
      end

      % Cell edit callback: UITable
      function UITableCellEdit(app, event)
         indices = event.Indices;
         newData = event.NewData;
         newSPL = newData - app.attenuation;
         app.UITable.Data.New(indices(1)) = {newSPL};
         app.isDirty = true;
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)
         app.SaveData();
      end

      % Value changed function: TransducerDropDown
      function TransducerDropDownValueChanged(app, event)
         if app.isDirty
            response = questdlg('You have unsaved changes to the calibration.', '', 'Save', 'Don''t save', 'Cancel', 'Save');
            if isequal(response, 'Save')
               app.SaveData();
            elseif isequal(response, 'Cancel')
               app.TransducerDropDown.Value = app.transducer;
               return;
            end
         end
         app.transducer = app.TransducerDropDown.Value;
         app.LoadCalibration();
      end

      % Button pushed function: MeterButton
      function MeterButtonPushed(app, event)
         app.soundMeter = Sound_Meter();
      end

      % Button pushed function: MeasureButton
      function MeasureButtonPushed(app, event)
         app.AcquireData();
      end

      % Button pushed function: StopMeasureButton
      function StopMeasureButtonPushed(app, event)
         app.StopMeasureButton.Enable = false;
         app.stopMeasurement = true;
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Acoustic Calibration';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.StopMeasureButton = uibutton(app.UIFigure, 'push');
         app.StopMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasureButtonPushed, true);
         app.StopMeasureButton.Text = 'Stop';
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Text = 'Stop';
         app.AttenuationdBEditFieldLabel = uilabel(app.UIFigure);
         app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
         app.AttenEditField = uieditfield(app.UIFigure, 'numeric');
         app.AttenEditField.Limits = [-Inf -3];
         app.AttenEditField.ValueChangedFcn = createCallbackFcn(app, @AttenEditFieldValueChanged, true);
         app.AttenEditField.Value = -3;
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
         app.PlayButton.Text = 'Play';
         app.SaveButton = uibutton(app.UIFigure, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.ExitButton = uibutton(app.UIFigure, 'push');
         app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
         app.ExitButton.Text = 'Exit';
         app.Panel = uipanel(app.UIFigure);
         app.UITable = uitable(app.Panel);
         app.UITable.ColumnName = {'Freq'; 'Old SPL'; 'New SPL'; 'Measured'};
         app.UITable.RowName = {''; ''};
         app.UITable.SelectionType = 'row';
         app.UITable.ColumnEditable = [false false false true];
         app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
         app.TransducerDropDownLabel = uilabel(app.UIFigure);
         app.TransducerDropDownLabel.Text = 'Transducer';
         app.TransducerDropDown = uidropdown(app.UIFigure);
         app.TransducerDropDown.Items = {'Default'};
         app.TransducerDropDown.Editable = 'on';
         app.TransducerDropDown.ValueChangedFcn = createCallbackFcn(app, @TransducerDropDownValueChanged, true);
         app.TransducerDropDown.Value = 'Default';
         app.MeterButton = uibutton(app.UIFigure, 'push');
         app.MeterButton.ButtonPushedFcn = createCallbackFcn(app, @MeterButtonPushed, true);
         app.MeterButton.Text = 'Meter';
         app.MeasureButton = uibutton(app.UIFigure, 'push');
         app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
         app.MeasureButton.Text = {'Measure'; ''};
         app.MethodDropDown = uidropdown(app.UIFigure);
         app.MethodDropDown.Items = {'Chirp', 'Tone sweep'};
         app.MethodDropDown.Editable = 'on';
         app.MethodDropDown.Value = 'Tone sweep';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = APTC_AcousticCalibration(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end