classdef APTC_ResponseWindow < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure             matlab.ui.Figure
      Button3              matlab.ui.control.Button
      Button2              matlab.ui.control.Button
      Button1              matlab.ui.control.Button
      PromptTextArea       matlab.ui.control.TextArea
      PromptTextAreaLabel  matlab.ui.control.Label
   end

   
   properties (Access = private)
      state % Description
      buttons = [] % Description
      Tstart_ms = NaN;
      Tdur_ms = NaN;
      correctInterval = 0;
      responseTimeStart = NaN;
      intertrialPause_s = 0.5;
      volumeControl = [];
      presentationMode;
      hkeepalive;
   end
   
   properties (SetAccess = private, GetAccess = public)
      Response = [];
   end
   
   properties (Access = public)
      DebugMode = 'off';
   end
   
   properties (Access = public)
      VisualCueDelay = 0;
   end
   
   methods (Access = public)
      
      function Initialize(app, numButtons, Tstart_ms, Tdur_ms, mode)
         app.presentationMode = mode;

         if ~strcmpi(mode, 'normal hearing')
            app.StartKeepAliveSound();
         end

         for k = 1:length(app.buttons)
            set(app.buttons(k), 'Visible', k <= numButtons);
         end

         xbutton = [33 184 335];
         pos = get(app.buttons(2), 'Position');
         if numButtons == 2
            pos(1) = xbutton(3);
         else
            pos(1) = xbutton(2);
         end
         set(app.buttons(2), 'Position', pos);

         app.Tstart_ms = Tstart_ms;
         app.Tdur_ms = Tdur_ms;
      end

      %--------------------------------------------------------------------
      function PromptToStart(app, prompt)
         app.PromptTextArea.Value = [prompt newline newline 'Press any key to start.'];
         app.state = 'WaitingToContinue';
      end

      %--------------------------------------------------------------------
      function ShowMessage(app, message, duration_s)
         app.PromptTextArea.Value = message;
         pause(duration_s);
         app.PromptTextArea.Value = '';
      end

      %--------------------------------------------------------------------
      function PlayTrial(app, Fs, signal, correctInterval)
         if isequal(app.DebugMode, 'fast')
            app.PromptTextArea.Value = 'debugging mode';
            app.state = 'Idle';
            drawnow;
            return;
         end

         app.Response = [];
         app.state = 'Playing';
         app.correctInterval = correctInterval;
         
         app.Button1.Enable = true;
         app.Button2.Enable = true;

         pause(app.intertrialPause_s);

         nvisdelay = round(Fs * app.VisualCueDelay/1000);
         if nvisdelay >= 0
            signal = [signal; zeros(nvisdelay, 2)];
         else
            signal = [zeros(abs(nvisdelay), 2); signal];
            nvisdelay = 0;
         end

         di = audiodevinfo();
         if strcmpi(app.presentationMode, 'normal hearing')
            idev = find(contains({di.output.Name}, 'Speakers (MOTU Pro Audio)'));
            if isempty(idev)
               error('Cannot find MOTU speaker channels');
            end
         else
            idev = 1;
         end

         app.SetPCVolume();
         signal = signal / max(abs(signal(:)));
         h = audioplayer(signal, Fs, 24, di.output(idev).ID); 
         h.StopFcn = @app.OnAudioFinished;
         
         if isempty(app.Tstart_ms)
            playblocking(h);
         
         else
            if ~strcmpi(app.presentationMode, 'normal hearing')
               pause(app.hkeepalive);
            end

            play(h);

            istart = round(Fs * app.Tstart_ms/1000) + nvisdelay;
            istop = istart + round(Fs * app.Tdur_ms/1000);

            for kb = 1:length(istart)
               while h.CurrentSample < istart(kb), end
               set(app.buttons(kb), 'BackgroundColor', [1 0 0]);
               drawnow;

               while h.CurrentSample < istop(kb) && h.CurrentSample > 1, end
               set(app.buttons(kb), 'BackgroundColor', [0.46 0.78 1]);
               drawnow;
            end

            while h.CurrentSample > 1, end
            if ~strcmpi(app.presentationMode, 'normal hearing')
               play(app.hkeepalive);
            end

         end
      end

      %--------------------------------------------------------------------
      function Finish(app, prompt)
         app.PromptTextArea.Value = prompt;
         app.state = 'Idle';
         pause(1);
         app.UIFigureCloseRequest([]);
      end
   end
   
   methods (Access = private)     
      function OnAudioFinished(app, ~, ~)
         if ~isequal(app.DebugMode, 'off')
            app.PromptTextArea.Value = 'debugging mode';
            app.state = 'Idle';
            uiresume(app.UIFigure);
            return;
         end

         app.responseTimeStart = tic;
         app.PromptTextArea.Value = 'Which interval?';
         app.state = 'WaitingForResponse';
      end      

      %--------------------------------------------------------------------
      function SetPCVolume(app)
         if ~isempty(app.volumeControl)
            volume = 1.0;
            if strcmpi(app.presentationMode, 'normal hearing')
               volume = 0.25;
            end

            app.volumeControl.SetVolume(volume);
         end         
      end
      
      %---------------------------------------------------------------
      function StartKeepAliveSound(app)
         duration = 600;                % duration in seconds
         amplitude = 0;                 % amplitude
         freq = 500;                    % frequency in Hertz

         Fs = 48000;                    % sampling rate
         T = 1/Fs;                      % sampling period
         t = 0:T:duration;              % time vector

         %create the signal
         signal = amplitude * cos(2 * pi * freq * t);

         app.hkeepalive = audioplayer(signal, Fs);
         app.hkeepalive.StopFcn = @app.OnAudioFinished;
         play(app.hkeepalive);
         disp('Bluetooth ''keep alive'' sound started.');
      end

      %---------------------------------------------------------------
      function StopKeepAliveSound(app)
         try
            %             stop(app.hkeepalive);
            delete(app.hkeepalive);
            disp('Bluetooth ''keep alive'' sound stopped.');
         catch
         end
      end

      %--------------------------------------------------------------------
      function InitializeVolumeControl(app)
         try
            NET.addAssembly(fullfile(fileparts(which(mfilename)), 'KLib.VolumeManager.dll'));
            app.volumeControl = KLib.VolumeManager;
%             app.volumeControl.SetVolume(0.25);
         catch ex
            errordlg(['Failed to initialize volume control: ' newline ex.message newline 'Make sure PC volume is maxed out.'], 'UWCAMP');
            app.volumeControl = [];
         end
      end
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         % Restore figure position
         origin = getpref('APTC_ResponseWindow', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end
         
         nbuttons = 3;
         app.buttons = zeros(nbuttons, 1);
         for kb = 1:nbuttons
            h = app.(sprintf('Button%d', kb));
            h.Visible = false;
            h.UserData = kb;
            app.buttons(kb) = h;
         end

         app.state = 'Idle';

         app.InitializeVolumeControl();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if ~isequal(app.state, 'Playing')
            if ~strcmpi(app.presentationMode, 'normal hearing')
               app.StopKeepAliveSound();
            end
            uiresume(app.UIFigure);
            pos = app.UIFigure.Position;
            setpref('APTC_ResponseWindow', 'Position', pos(1:2));
            delete(app)
         end
      end

      % Key release function: UIFigure
      function UIFigureKeyRelease(app, event)
         if isequal(app.state, 'WaitingToContinue')
            app.PromptTextArea.Value = '';
            app.state = 'Idle';
            uiresume(app.UIFigure);
         end
      end

      % Button pushed function: Button1, Button2, Button3
      function ButtonPushed(app, event)
         if isequal(app.state, 'WaitingForResponse')
            app.Response.correctResponse = app.correctInterval;
            app.Response.actualResponse = event.Source.UserData;
            app.Response.reactionTime = toc(app.responseTimeStart);

            if app.Response.actualResponse == app.Response.correctResponse
               app.PromptTextArea.Value = '--- CORRECT ---';
            else
               app.PromptTextArea.Value = '--- WRONG ---';
            end
            
            app.state = 'Idle';
            uiresume(app.UIFigure);
         end         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
         app.PromptTextAreaLabel = uilabel(app.UIFigure);
         app.PromptTextAreaLabel.Visible = 'off';
         app.PromptTextAreaLabel.Text = 'Prompt';
         app.PromptTextArea = uitextarea(app.UIFigure);
         app.PromptTextArea.Editable = 'off';
         app.PromptTextArea.Value = {'Prompt'};
         app.Button1 = uibutton(app.UIFigure, 'push');
         app.Button1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
         app.Button1.Text = '1';
         app.Button2 = uibutton(app.UIFigure, 'push');
         app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
         app.Button2.Text = {'2'; ''};
         app.Button3 = uibutton(app.UIFigure, 'push');
         app.Button3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
         app.Button3.Text = '3';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = APTC_ResponseWindow

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end