classdef Sound_Meter < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                        matlab.ui.Figure
      dBSPLLabel_2                    matlab.ui.control.Label
      VrmsField                       matlab.ui.control.NumericEditField
      SPLField                        matlab.ui.control.NumericEditField
      dBSPLLabel                      matlab.ui.control.Label
      StopButton                      matlab.ui.control.Button
      MicSensitivityEditField         matlab.ui.control.NumericEditField
      MicsensitivityEditFieldLabel    matlab.ui.control.Label
      MeasureButton                   matlab.ui.control.Button
      PistonphoneEditField            matlab.ui.control.NumericEditField
      PistonphonedBSPLEditFieldLabel  matlab.ui.control.Label
      ResolutionHzEditField           matlab.ui.control.NumericEditField
      ResolutionHzEditFieldLabel      matlab.ui.control.Label
      RunButton                       matlab.ui.control.Button
      TimeAxes                        matlab.ui.control.UIAxes
      FreqAxes                        matlab.ui.control.UIAxes
   end

   
   methods (Access = private)
      
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         app.ResolutionHzEditField.Value = getsharedpref('SoundMeter', 'Resolution_Hz', 1);
         app.PistonphoneEditField.Value = getsharedpref('SoundMeter', 'Pistonphone_dB', 94);
         app.MicSensitivityEditField.Value = getsharedpref('SoundMeter', 'Sensitivity', -133);

         % Restore figure position
         pos = app.UIFigure.Position;
         origin = getsharedpref('SoundMeter', 'Position', []);
         screenSize = get(0, 'ScreenSize');
         if ~isempty(origin) && origin(1) < screenSize(3) 
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         pos = app.UIFigure.Position;
         setsharedpref('SoundMeter', 'Position', pos(1:2));
         delete(app)         
      end

      % Button pushed function: RunButton
      function RunButtonPushed(app, event)
         
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         
      end

      % Button pushed function: MeasureButton
      function MeasureButtonPushed(app, event)
         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Sound Meter';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.FreqAxes = uiaxes(app.UIFigure);
         xlabel(app.FreqAxes, 'Frequency (Hz)')
         ylabel(app.FreqAxes, 'dB SPL')
         zlabel(app.FreqAxes, 'Z')
         app.TimeAxes = uiaxes(app.UIFigure);
         xlabel(app.TimeAxes, 'Time (ms)')
         ylabel(app.TimeAxes, 'Amplitude')
         zlabel(app.TimeAxes, 'Z')
         app.RunButton = uibutton(app.UIFigure, 'push');
         app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
         app.RunButton.Text = 'Run';
         app.ResolutionHzEditFieldLabel = uilabel(app.UIFigure);
         app.ResolutionHzEditFieldLabel.Text = 'Resolution (Hz)';
         app.ResolutionHzEditField = uieditfield(app.UIFigure, 'numeric');
         app.PistonphonedBSPLEditFieldLabel = uilabel(app.UIFigure);
         app.PistonphonedBSPLEditFieldLabel.Text = 'Pistonphone (dB SPL)';
         app.PistonphoneEditField = uieditfield(app.UIFigure, 'numeric');
         app.MeasureButton = uibutton(app.UIFigure, 'push');
         app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
         app.MeasureButton.Text = 'Measure';
         app.MicsensitivityEditFieldLabel = uilabel(app.UIFigure);
         app.MicsensitivityEditFieldLabel.Text = 'Mic sensitivity';
         app.MicSensitivityEditField = uieditfield(app.UIFigure, 'numeric');
         app.MicSensitivityEditField.Editable = 'off';
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Enable = 'off';
         app.StopButton.Text = {'Stop'; ''};
         app.dBSPLLabel = uilabel(app.UIFigure);
         app.dBSPLLabel.Text = 'dB SPL';
         app.SPLField = uieditfield(app.UIFigure, 'numeric');
         app.SPLField.ValueDisplayFormat = '%11.1f';
         app.VrmsField = uieditfield(app.UIFigure, 'numeric');
         app.VrmsField.ValueDisplayFormat = '%11.2f';
         app.dBSPLLabel_2 = uilabel(app.UIFigure);
         app.dBSPLLabel_2.Text = 'dB Vrms';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = Sound_Meter

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end